classdef TeachFunctionality_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        EstopStatusLamp          matlab.ui.control.Lamp
        EstopStatusLampLabel     matlab.ui.control.Label
        ExitButton               matlab.ui.control.Button
        RunButton                matlab.ui.control.Button
        Button_24                matlab.ui.control.Button
        Button_23                matlab.ui.control.Button
        Button_22                matlab.ui.control.Button
        Button_21                matlab.ui.control.Button
        Button_20                matlab.ui.control.Button
        Button_19                matlab.ui.control.Button
        Button_18                matlab.ui.control.Button
        Button_17                matlab.ui.control.Button
        Button_16                matlab.ui.control.Button
        Button_15                matlab.ui.control.Button
        Button_14                matlab.ui.control.Button
        Button_13                matlab.ui.control.Button
        Wrist3EditField          matlab.ui.control.NumericEditField
        Wrist3EditFieldLabel     matlab.ui.control.Label
        Wrist2EditField          matlab.ui.control.NumericEditField
        Wrist2EditFieldLabel     matlab.ui.control.Label
        ElbowEditField           matlab.ui.control.NumericEditField
        ElbowEditFieldLabel      matlab.ui.control.Label
        Wrist1EditField          matlab.ui.control.NumericEditField
        Wrist1EditFieldLabel     matlab.ui.control.Label
        ShoulderEditField        matlab.ui.control.NumericEditField
        ShoulderEditFieldLabel   matlab.ui.control.Label
        BaseEditField            matlab.ui.control.NumericEditField
        BaseEditFieldLabel       matlab.ui.control.Label
        EmergencyStopButton      matlab.ui.control.Button
        JointPositionsLabel      matlab.ui.control.Label
        Button_12                matlab.ui.control.Button
        Button_11                matlab.ui.control.Button
        Button_10                matlab.ui.control.Button
        Button_9                 matlab.ui.control.Button
        Button_8                 matlab.ui.control.Button
        Button_7                 matlab.ui.control.Button
        RZradEditField           matlab.ui.control.NumericEditField
        RZradEditFieldLabel      matlab.ui.control.Label
        RYradEditField           matlab.ui.control.NumericEditField
        RYradEditFieldLabel      matlab.ui.control.Label
        RXradEditField           matlab.ui.control.NumericEditField
        RXradEditFieldLabel      matlab.ui.control.Label
        Button_6                 matlab.ui.control.Button
        Button_5                 matlab.ui.control.Button
        Button_4                 matlab.ui.control.Button
        Button_3                 matlab.ui.control.Button
        Button_2                 matlab.ui.control.Button
        Button                   matlab.ui.control.Button
        ZmmEditField             matlab.ui.control.NumericEditField
        ZmmEditFieldLabel        matlab.ui.control.Label
        YmmEditField             matlab.ui.control.NumericEditField
        YmmEditFieldLabel        matlab.ui.control.Label
        XmmEditField             matlab.ui.control.NumericEditField
        XmmEditFieldLabel        matlab.ui.control.Label
        ActiveTCPLabel           matlab.ui.control.Label
        TeachFunctionalityLabel  matlab.ui.control.Label
        UIAxes                   matlab.ui.control.UIAxes
        ContextMenu              matlab.ui.container.ContextMenu
        Menu                     matlab.ui.container.Menu
        Menu2                    matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
          
          app.XmmEditField.Value =  app.XmmEditField.Value + 1;
            
        end

        % Value changed function: XmmEditField
        function XmmEditFieldValueChanged(app, event)
            Xmm = app.XmmEditField.Value;
            app.XmmEditField.Value = 0;
            
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            
            app.XmmEditField.Value = app.XmmEditField.Value - 1
        end

        % Value changed function: YmmEditField
        function YmmEditFieldValueChanged(app, event)
            Ymm = app.YmmEditField.Value;
            app.YmmEditField.Value = 0;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            app.YmmEditField.Value = app.YmmEditField.Value + 1;
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            app.YmmEditField.Value = app.YmmEditField.Value - 1;
        end

        % Value changed function: ZmmEditField
        function ZmmEditFieldValueChanged(app, event)
            Zmm = app.ZmmEditField.Value;
            app.ZmmEditField.Value = 0
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            app.ZmmEditField.Value = app.ZmmEditField.Value + 1;
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            app.ZmmEditField.Value = app.ZmmEditField.Value - 1;
        end

        % Value changed function: BaseEditField
        function BaseEditFieldValueChanged(app, event)
            Base = app.BaseEditField.Value;
            app.BaseEditField.Value = 0;            
        end

        % Button pushed function: Button_13
        function Button_13Pushed(app, event)
            app.BaseEditField.Value = app.BaseEditField.Value + 1;
        end

        % Button pushed function: Button_19
        function Button_19Pushed(app, event)
            app.BaseEditField.Value = app.BaseEditField.Value - 1;
        end

        % Value changed function: RXradEditField
        function RXradEditFieldValueChanged(app, event)
            Rxrad = app.RXradEditField.Value;
            app.RXradEditField.Value = 0;
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            app.RXradEditField.Value = app.RXradEditField.Value + 1;
        end

        % Button pushed function: Button_10
        function Button_10Pushed(app, event)
            app.RXradEditField.Value = app.RXradEditField.Value - 1;
        end

        % Value changed function: RYradEditField
        function RYradEditFieldValueChanged(app, event)
            RYrad = app.RYradEditField.Value;
            app.RYradEditField.Value = 0;
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            app.RYradEditField.Value = app.RYradEditField.Value + 1;
        end

        % Button pushed function: Button_11
        function Button_11Pushed(app, event)
            app.RYradEditField.Value = app.RYradEditField.Value - 1;
        end

        % Value changed function: RZradEditField
        function RZradEditFieldValueChanged(app, event)
            RZrad = app.RZradEditField.Value;
            app.RZradEditField.Value = 0;
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            app.RZradEditField.Value = app.RZradEditField.Value + 1;
        end

        % Button pushed function: Button_12
        function Button_12Pushed(app, event)
            app.RZradEditField.Value = app.RZradEditField.Value - 1;
        end

        % Value changed function: ShoulderEditField
        function ShoulderEditFieldValueChanged(app, event)
            Shoulder = app.ShoulderEditField.Value;
            app.ShoulderEditField.Value = 0;
        end

        % Button pushed function: Button_14
        function Button_14Pushed(app, event)
            app.ShoulderEditField.Value = app.ShoulderEditField.Value + 1;
        end

        % Button pushed function: Button_20
        function Button_20Pushed(app, event)
            app.ShoulderEditField.Value = app.ShoulderEditField.Value - 1;
        end

        % Value changed function: ElbowEditField
        function ElbowEditFieldValueChanged(app, event)
            Elbow = app.ElbowEditField.Value;
            app.ElbowEditField.Value = 0;
        end

        % Button pushed function: Button_15
        function Button_15Pushed(app, event)
            app.ElbowEditField.Value = app.ElbowEditField.Value + 1;
        end

        % Button pushed function: Button_21
        function Button_21Pushed(app, event)
            app.ElbowEditField.Value = app.ElbowEditField.Value - 1;
        end

        % Value changed function: Wrist1EditField
        function Wrist1EditFieldValueChanged(app, event)
            Wrist1 = app.Wrist1EditField.Value;
            app.Wrist1EditField.Value = 0;
        end

        % Button pushed function: Button_16
        function Button_16Pushed(app, event)
            app.Wrist1EditField.Value = app.Wrist1EditField.Value + 1;
        end

        % Button pushed function: Button_22
        function Button_22Pushed(app, event)
            app.Wrist1EditField.Value = app.Wrist1EditField.Value - 1;
        end

        % Value changed function: Wrist2EditField
        function Wrist2EditFieldValueChanged(app, event)
            Wrist2 = app.Wrist2EditField.Value;
            app.Wrist2EditField.Value = 0;
        end

        % Button pushed function: Button_17
        function Button_17Pushed(app, event)
            app.Wrist2EditField.Value = app.Wrist2EditField.Value + 1;
        end

        % Button pushed function: Button_23
        function Button_23Pushed(app, event)
            app.Wrist2EditField.Value = app.Wrist2EditField.Value - 1;
        end

        % Value changed function: Wrist3EditField
        function Wrist3EditFieldValueChanged(app, event)
            Wrist3 = app.Wrist3EditField.Value;
            app.Wrist3EditField.Value = 0;
        end

        % Button pushed function: Button_18
        function Button_18Pushed(app, event)
            app.Wrist3EditField.Value = app.Wrist3EditField.Value + 1;
        end

        % Button pushed function: Button_24
        function Button_24Pushed(app, event)
            app.Wrist3EditField.Value = app.Wrist3EditField.Value - 1;
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            closereq();
            MainMenu_GUI
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            x = 1:10;
            y = 1:10;
            z = 1:10;  
plot3(app.UIAxes,x,y,z);
testrobot
        end

        % Button pushed function: EmergencyStopButton
        function EmergencyStopButtonPushed(app, event)
            app.EmergencyStopButton.Value = Estop;
            Estop = Estop + 1;
            if Estop == 0
                app.EstopStatusLamp.Color = 'green'
            end
         if Estop == 1
                app.EstopStatusLamp.Color = 'red'
         end
         if Estop == 2;
             app.EstopStatusLamp.Color = 'yellow'
         end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            Estop = 0;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.TeachFunctionalityLabel = uilabel(app.UIFigure);
            app.TeachFunctionalityLabel.Text = 'Teach Functionality';
            app.ActiveTCPLabel = uilabel(app.UIFigure);
            app.ActiveTCPLabel.Text = 'Active TCP';
            app.XmmEditFieldLabel = uilabel(app.UIFigure);
            app.XmmEditFieldLabel.Text = 'X (mm)';
            app.XmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.XmmEditField.ValueChangedFcn = createCallbackFcn(app, @XmmEditFieldValueChanged, true);
            app.YmmEditFieldLabel = uilabel(app.UIFigure);
            app.YmmEditFieldLabel.Text = 'Y (mm)';
            app.YmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.YmmEditField.ValueChangedFcn = createCallbackFcn(app, @YmmEditFieldValueChanged, true);
            app.ZmmEditFieldLabel = uilabel(app.UIFigure);
            app.ZmmEditFieldLabel.Text = 'Z (mm)';
            app.ZmmEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZmmEditField.ValueChangedFcn = createCallbackFcn(app, @ZmmEditFieldValueChanged, true);
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '+';
            app.Button_2 = uibutton(app.UIFigure, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '+';
            app.Button_3 = uibutton(app.UIFigure, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = '+';
            app.Button_4 = uibutton(app.UIFigure, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Text = '-';
            app.Button_5 = uibutton(app.UIFigure, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '-';
            app.Button_6 = uibutton(app.UIFigure, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '-';
            app.RXradEditFieldLabel = uilabel(app.UIFigure);
            app.RXradEditFieldLabel.Text = 'RX (rad)';
            app.RXradEditField = uieditfield(app.UIFigure, 'numeric');
            app.RXradEditField.ValueChangedFcn = createCallbackFcn(app, @RXradEditFieldValueChanged, true);
            app.RYradEditFieldLabel = uilabel(app.UIFigure);
            app.RYradEditFieldLabel.Text = 'RY (rad)';
            app.RYradEditField = uieditfield(app.UIFigure, 'numeric');
            app.RYradEditField.ValueChangedFcn = createCallbackFcn(app, @RYradEditFieldValueChanged, true);
            app.RZradEditFieldLabel = uilabel(app.UIFigure);
            app.RZradEditFieldLabel.Text = 'RZ (rad)';
            app.RZradEditField = uieditfield(app.UIFigure, 'numeric');
            app.RZradEditField.ValueChangedFcn = createCallbackFcn(app, @RZradEditFieldValueChanged, true);
            app.Button_7 = uibutton(app.UIFigure, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Text = '+';
            app.Button_8 = uibutton(app.UIFigure, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Text = '+';
            app.Button_9 = uibutton(app.UIFigure, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Text = '+';
            app.Button_10 = uibutton(app.UIFigure, 'push');
            app.Button_10.ButtonPushedFcn = createCallbackFcn(app, @Button_10Pushed, true);
            app.Button_10.Text = '-';
            app.Button_11 = uibutton(app.UIFigure, 'push');
            app.Button_11.ButtonPushedFcn = createCallbackFcn(app, @Button_11Pushed, true);
            app.Button_11.Text = '-';
            app.Button_12 = uibutton(app.UIFigure, 'push');
            app.Button_12.ButtonPushedFcn = createCallbackFcn(app, @Button_12Pushed, true);
            app.Button_12.Text = '-';
            app.JointPositionsLabel = uilabel(app.UIFigure);
            app.JointPositionsLabel.Text = 'Joint Positions';
            app.EmergencyStopButton = uibutton(app.UIFigure, 'push');
            app.EmergencyStopButton.ButtonPushedFcn = createCallbackFcn(app, @EmergencyStopButtonPushed, true);
            app.EmergencyStopButton.Text = 'Emergency Stop';
            app.BaseEditFieldLabel = uilabel(app.UIFigure);
            app.BaseEditFieldLabel.Text = 'Base';
            app.BaseEditField = uieditfield(app.UIFigure, 'numeric');
            app.BaseEditField.ValueChangedFcn = createCallbackFcn(app, @BaseEditFieldValueChanged, true);
            app.ShoulderEditFieldLabel = uilabel(app.UIFigure);
            app.ShoulderEditFieldLabel.Text = 'Shoulder';
            app.ShoulderEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShoulderEditField.ValueChangedFcn = createCallbackFcn(app, @ShoulderEditFieldValueChanged, true);
            app.Wrist1EditFieldLabel = uilabel(app.UIFigure);
            app.Wrist1EditFieldLabel.Text = 'Wrist 1';
            app.Wrist1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Wrist1EditField.ValueChangedFcn = createCallbackFcn(app, @Wrist1EditFieldValueChanged, true);
            app.ElbowEditFieldLabel = uilabel(app.UIFigure);
            app.ElbowEditFieldLabel.Text = 'Elbow';
            app.ElbowEditField = uieditfield(app.UIFigure, 'numeric');
            app.ElbowEditField.ValueChangedFcn = createCallbackFcn(app, @ElbowEditFieldValueChanged, true);
            app.Wrist2EditFieldLabel = uilabel(app.UIFigure);
            app.Wrist2EditFieldLabel.Text = 'Wrist 2';
            app.Wrist2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Wrist2EditField.ValueChangedFcn = createCallbackFcn(app, @Wrist2EditFieldValueChanged, true);
            app.Wrist3EditFieldLabel = uilabel(app.UIFigure);
            app.Wrist3EditFieldLabel.Text = 'Wrist 3';
            app.Wrist3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Wrist3EditField.ValueChangedFcn = createCallbackFcn(app, @Wrist3EditFieldValueChanged, true);
            app.Button_13 = uibutton(app.UIFigure, 'push');
            app.Button_13.ButtonPushedFcn = createCallbackFcn(app, @Button_13Pushed, true);
            app.Button_13.Text = '+';
            app.Button_14 = uibutton(app.UIFigure, 'push');
            app.Button_14.ButtonPushedFcn = createCallbackFcn(app, @Button_14Pushed, true);
            app.Button_14.Text = '+';
            app.Button_15 = uibutton(app.UIFigure, 'push');
            app.Button_15.ButtonPushedFcn = createCallbackFcn(app, @Button_15Pushed, true);
            app.Button_15.Text = '+';
            app.Button_16 = uibutton(app.UIFigure, 'push');
            app.Button_16.ButtonPushedFcn = createCallbackFcn(app, @Button_16Pushed, true);
            app.Button_16.Text = '+';
            app.Button_17 = uibutton(app.UIFigure, 'push');
            app.Button_17.ButtonPushedFcn = createCallbackFcn(app, @Button_17Pushed, true);
            app.Button_17.Text = '+';
            app.Button_18 = uibutton(app.UIFigure, 'push');
            app.Button_18.ButtonPushedFcn = createCallbackFcn(app, @Button_18Pushed, true);
            app.Button_18.Text = '+';
            app.Button_19 = uibutton(app.UIFigure, 'push');
            app.Button_19.ButtonPushedFcn = createCallbackFcn(app, @Button_19Pushed, true);
            app.Button_19.Text = '-';
            app.Button_20 = uibutton(app.UIFigure, 'push');
            app.Button_20.ButtonPushedFcn = createCallbackFcn(app, @Button_20Pushed, true);
            app.Button_20.Text = '-';
            app.Button_21 = uibutton(app.UIFigure, 'push');
            app.Button_21.ButtonPushedFcn = createCallbackFcn(app, @Button_21Pushed, true);
            app.Button_21.Text = '-';
            app.Button_22 = uibutton(app.UIFigure, 'push');
            app.Button_22.ButtonPushedFcn = createCallbackFcn(app, @Button_22Pushed, true);
            app.Button_22.Text = '-';
            app.Button_23 = uibutton(app.UIFigure, 'push');
            app.Button_23.ButtonPushedFcn = createCallbackFcn(app, @Button_23Pushed, true);
            app.Button_23.Text = '-';
            app.Button_24 = uibutton(app.UIFigure, 'push');
            app.Button_24.ButtonPushedFcn = createCallbackFcn(app, @Button_24Pushed, true);
            app.Button_24.Text = '-';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            app.EstopStatusLampLabel = uilabel(app.UIFigure);
            app.EstopStatusLampLabel.Text = 'Estop Status';
            app.EstopStatusLamp = uilamp(app.UIFigure);
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TeachFunctionality_GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end